classdef DriversView2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DriversViewApp               matlab.ui.Figure
        SwitchtoOperatorsViewButton  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ParkingSpaces   
        OperatorsViewReference 
        CarImages 
        CarImage
        HighlightedSlot 
    end
    
    methods (Access = private)
        
        function initializeParking(app)
    % Set up the axes
    app.UIAxes.XLim = [0 1400];
    app.UIAxes.YLim = [0 800];
    app.UIAxes.XTick = [];
    app.UIAxes.YTick = [];
    axis(app.UIAxes, 'equal');
    title(app.UIAxes, 'Parking Layout');
    hold(app.UIAxes, 'on');

    % parking lot outline
    rectangle(app.UIAxes, 'Position', [0 0 1400 1000], 'EdgeColor', 'k', 'LineWidth', 2);

    % large black rectangle
    rectangle(app.UIAxes, 'Position', [0 0 1400 1000], 'FaceColor', [46/255, 45/255, 45/255], 'EdgeColor', 'none');

    % entrance and exit
    rectangle(app.UIAxes, 'Position', [0 0 100 100], 'FaceColor', [0.8 1 0.8], 'EdgeColor', 'k');
    text(app.UIAxes, 50, 50, 'Entrance', 'HorizontalAlignment', 'center');
    rectangle(app.UIAxes, 'Position', [1300 900 100 100], 'FaceColor', [1 0.8 0.8], 'EdgeColor', 'k');
    text(app.UIAxes, 1350, 950, 'Exit', 'HorizontalAlignment', 'center');

    % parking slot dimensions
    app.ParkingSpaces = gobjects(24, 1);
    spaceWidth = 90;
    spaceHeight = 150;
    middleGap = 100;

    % parking slot positions
    for row = 1:4
        for col = 1:6
            index = (row-1)*6 + col;
            
            % y-coordinate
            if row <= 2
                y = 135 + (row-1)*(spaceHeight + 20);
            else
                y = 600 + (row-3)*(spaceHeight + 20);
            end

            % x-coordinate with middle gap
            if col <= 3
                x = 320 + (col-1)*(spaceWidth + 30);
            else
                x = 320 + (col-1)*(spaceWidth + 30) + middleGap;
            end

            app.ParkingSpaces(index) = rectangle(app.UIAxes, 'Position', [x y spaceWidth spaceHeight], ...
                'EdgeColor', 'w', 'LineWidth', 1.5);
            text(app.UIAxes, x+spaceWidth/2, y+spaceHeight/2, num2str(index), ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','Color', 'w');
        end
    end

    % Grey lane marking
    y = 540;
    line(app.UIAxes, [320 650], [y y], 'Color', [0.7 0.7 0.7], 'LineStyle', '--', 'LineWidth', 2);
    line(app.UIAxes, [780 1110], [y y], 'Color', [0.7 0.7 0.7], 'LineStyle', '--', 'LineWidth', 2);

    % Add arrows to show car flow
    % Arrows in the driving lanes
    arrowColor = [0.7 0.7 0.7]; % Light grey color
    
    % Bottom lane arrows
    annotation(app.DriversViewApp, 'arrow', [0.24 0.47], [0.15 0.15], 'Color', arrowColor, 'LineWidth', 2);
    annotation(app.DriversViewApp, 'arrow', [0.56 0.79], [0.15 0.15], 'Color', arrowColor, 'LineWidth', 2);
    
    % Top lane arrows
    annotation(app.DriversViewApp, 'arrow', [0.47 0.24], [0.86 0.86], 'Color', arrowColor, 'LineWidth', 2);
    annotation(app.DriversViewApp, 'arrow', [0.79 0.56], [0.86 0.86], 'Color', arrowColor, 'LineWidth', 2);

    % Vertical arrow from slot 19 to slot 1
    annotation(app.DriversViewApp, 'arrow', [0.18 0.18], [0.85 0.6], 'Color', arrowColor, 'LineWidth', 2);

    % Vertical arrow from slot 19 to slot 1
    annotation(app.DriversViewApp, 'arrow', [0.18 0.18], [0.5 0.2], 'Color', arrowColor, 'LineWidth', 2);

    % Vertical arrow from slot 19 to slot 1
    annotation(app.DriversViewApp, 'arrow', [0.83 0.83], [0.2 0.50], 'Color', arrowColor, 'LineWidth', 2);

    % Vertical arrow from slot 19 to slot 1
    annotation(app.DriversViewApp, 'arrow', [0.83 0.83], [0.60 0.85], 'Color', arrowColor, 'LineWidth', 2);

    % Add horizontal slanted pedestrian lines (yellow)
    crosswalkWidth = 880;
    crosswalkLength = 50;
    crosswalkX = 280; % X-coordinate of the start of the crosswalk
    crosswalkY = 470; % Y-coordinate of the start of the crosswalk
    lineSpacing = 20; % Space between lines
    lineWidth = 2; % Width of each line
    lineColor = [1 1 0]; % Yellow 

    for i = 0:lineSpacing:(crosswalkWidth-lineWidth)
        x1 = crosswalkX + i;
        y1 = crosswalkY;
        x2 = crosswalkX + i + 30; % Subtract 3 to create a slant
        y2 = crosswalkY + crosswalkLength;
        line(app.UIAxes, [x1 x2], [y1 y2], 'Color', lineColor, 'LineWidth', lineWidth);
    end

    % Add vertical slanted pedestrian lines (yellow) going from top to bottom
    crosswalkWidth = 50;
    crosswalkLength = 880;
    crosswalkX = 1200; % X-coordinate of the start of the crosswalk
    crosswalkY = 50; % Y-coordinate of the start of the crosswalk (top of the layout)
    lineSpacing = 20; % Space between lines
    lineWidth = 2; % Width of each line
    lineColor = [1 1 0]; % Yellow 
    
    for i = 0:lineSpacing:(crosswalkLength-lineWidth)
        x1 = crosswalkX;
        y1 = crosswalkY + i;
        x2 = crosswalkX + crosswalkWidth;
        y2 = crosswalkY + i + 30; % Add 3 to create a downward slant
        line(app.UIAxes, [x1 x2], [y1 y2], 'Color', lineColor, 'LineWidth', lineWidth);
    end

    hold(app.UIAxes, 'off');
end


   end


    methods (Access = public)
        
           function setOperatorsViewReference(app, OperatorsViewRef)
                app.OperatorsViewReference = OperatorsViewRef;
                app.updateAllCars();
                app.highlightNearestAvailableSlot();
           end


        % FUNCTION updateCarImage
       function updateCarImage(app, slotNumber, isOccupied)
    if isOccupied
        pos = app.ParkingSpaces(slotNumber).Position;
        % Move the car 10 units to the left
        carPos = [pos(1) - 25, pos(2) + 10, pos(3) - 20, pos(4) - 20];
        set(app.CarImages(slotNumber), 'XData', carPos(1), 'YData', carPos(2), 'Visible', 'on');
    else
        set(app.CarImages(slotNumber), 'Visible', 'off');
    end
       end
        function initializeCarImages(app)
    app.CarImages = gobjects(24, 1);
    [img, ~, alpha] = imread('car.png');  
    img = imresize(img, [130 150]); 
    alpha = imresize(alpha, [130 150]);

    for i = 1:24
        app.CarImages(i) = image(app.UIAxes, 'CData', img, 'AlphaData', alpha);
        app.CarImages(i).Visible = 'off';
    end
end


          function updateAllCars(app)
           disp('Entering updateAllCars method'); % Debug message
          
           if ~isempty(app.OperatorsViewReference) && isvalid(app.OperatorsViewReference)
               disp('OperatorsViewReference is valid'); % Debug message
              
               for i = 1:24
                   checkboxName = sprintf('Slot%dCheckBox', i);
                   if isprop(app.OperatorsViewReference, checkboxName)
                       isOccupied = app.OperatorsViewReference.(checkboxName).Value;
                       disp(['Slot ', num2str(i), ' occupied: ', num2str(isOccupied)]); % Debug message
                       app.updateCarImage(i, isOccupied);
                   else
                       disp(['Property ', checkboxName, ' not found in OperatorsViewReference']); % Debug message
                   end
               end
           else
               disp('OperatorsViewReference is empty or invalid'); % Debug message
           end
          
           % Force a redraw of the axes
           drawnow;
          
           % Check if car images are visible
           visibleCars = sum(arrayfun(@(x) strcmp(x.Visible, 'on'), app.CarImages));
           disp(['Number of visible cars: ', num2str(visibleCars)]); % Debug message

           app.highlightNearestAvailableSlot()
       end


       function highlightNearestAvailableSlot(app)
            % Get the occupancy status of all slots
            occupiedSlots = false(24, 1);
            for i = 1:24
                checkboxName = sprintf('Slot%dCheckBox', i);
                if isprop(app.OperatorsViewReference, checkboxName)
                    occupiedSlots(i) = app.OperatorsViewReference.(checkboxName).Value;
                end
            end
            
            % Find available slots
            availableSlots = find(~occupiedSlots);
            
            if ~isempty(availableSlots)
                % Calculate distances from entrance to available slots
                entrancePos = [5, 5]; % Approximate position of the entrance
                distances = zeros(length(availableSlots), 1);
                
                for i = 1:length(availableSlots)
                    slotPos = app.ParkingSpaces(availableSlots(i)).Position;
                    slotCenter = [slotPos(1) + slotPos(3)/2, slotPos(2) + slotPos(4)/2];
                    distances(i) = norm(slotCenter - entrancePos);
                end
                
                % Find the nearest available slot
                [~, nearestIndex] = min(distances);
                nearestSlot = availableSlots(nearestIndex);
                
                % Highlight the nearest available slot
                if ~isempty(app.HighlightedSlot)
                    set(app.ParkingSpaces(app.HighlightedSlot), 'EdgeColor', 'w');
                end
                set(app.ParkingSpaces(nearestSlot), 'EdgeColor', 'g', 'LineWidth', 2);
                app.HighlightedSlot = nearestSlot;
            else
                % If no slots are available, remove any existing highlight
                if ~isempty(app.HighlightedSlot)
                    set(app.ParkingSpaces(app.HighlightedSlot), 'EdgeColor', 'w', 'LineWidth', 1.5);
                    app.HighlightedSlot = [];
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DriversViewApp.Visible = 'on';
            app.initializeParking();
            app.initializeCarImages();
            app.HighlightedSlot = []; 
            app.updateAllCars();
            disp(['UIAxes position: ', mat2str(app.UIAxes.Position)]);
            disp(['UIAxes visible: ', app.UIAxes.Visible]);
        end

        % Button pushed function: SwitchtoOperatorsViewButton
        function SwitchtoOperatorsViewButtonPushed(app, event)
        % Hide the current app
        app.DriversViewApp.Visible = 'off';
        
        % Show the OperatorsView app
        if ~isempty(app.OperatorsViewReference) && isvalid(app.OperatorsViewReference)
            app.OperatorsViewReference.UIFigure.Visible = 'on';
            app.updateAllCars();
        else
            % If OperatorsView reference is not set or invalid, create a new instance
            app.OperatorsViewReference = OperatorsView;
            app.OperatorsViewReference.DriversView2Reference = app;
            app.OperatorsViewReference.UIFigure.Visible = 'on';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DriversViewApp = uifigure('Visible', 'off');
            app.DriversViewApp.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.DriversViewApp);
            title(app.UIAxes, 'Parking Layout')
            zlabel(app.UIAxes, 'Z')
            app.SwitchtoOperatorsViewButton = uibutton(app.DriversViewApp, 'push');
            app.SwitchtoOperatorsViewButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchtoOperatorsViewButtonPushed, true);
            app.SwitchtoOperatorsViewButton.Text = 'Switch to Operator''s View';
            % Show the figure after all components are created
            app.DriversViewApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DriversView2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DriversViewApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DriversViewApp)
        end
    end
end