classdef OperatorsView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SwitchtoDriversViewButton  matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        OpenLabel_24               matlab.ui.control.Label
        OpenLabel_23               matlab.ui.control.Label
        OpenLabel_22               matlab.ui.control.Label
        OpenLabel_21               matlab.ui.control.Label
        OpenLabel_20               matlab.ui.control.Label
        OpenLabel_19               matlab.ui.control.Label
        OpenLabel_18               matlab.ui.control.Label
        OpenLabel_17               matlab.ui.control.Label
        OpenLabel_16               matlab.ui.control.Label
        OpenLabel_15               matlab.ui.control.Label
        OpenLabel_14               matlab.ui.control.Label
        OpenLabel_13               matlab.ui.control.Label
        OpenLabel_12               matlab.ui.control.Label
        OpenLabel_11               matlab.ui.control.Label
        OpenLabel_10               matlab.ui.control.Label
        OpenLabel_9                matlab.ui.control.Label
        OpenLabel_8                matlab.ui.control.Label
        OpenLabel_7                matlab.ui.control.Label
        OpenLabel_6                matlab.ui.control.Label
        OpenLabel_5                matlab.ui.control.Label
        OpenLabel_4                matlab.ui.control.Label
        OpenLabel_3                matlab.ui.control.Label
        OpenLabel_2                matlab.ui.control.Label
        OpenLabel                  matlab.ui.control.Label
        Lamp_24                    matlab.ui.control.Lamp
        Lamp_23                    matlab.ui.control.Lamp
        Lamp_22                    matlab.ui.control.Lamp
        Lamp_21                    matlab.ui.control.Lamp
        Lamp_20                    matlab.ui.control.Lamp
        Lamp_19                    matlab.ui.control.Lamp
        Lamp_18                    matlab.ui.control.Lamp
        Lamp_17                    matlab.ui.control.Lamp
        Lamp_16                    matlab.ui.control.Lamp
        Lamp_15                    matlab.ui.control.Lamp
        Lamp_14                    matlab.ui.control.Lamp
        Lamp_13                    matlab.ui.control.Lamp
        Lamp_12                    matlab.ui.control.Lamp
        Lamp_11                    matlab.ui.control.Lamp
        Lamp_10                    matlab.ui.control.Lamp
        Lamp_9                     matlab.ui.control.Lamp
        Lamp_8                     matlab.ui.control.Lamp
        Lamp_7                     matlab.ui.control.Lamp
        Lamp_6                     matlab.ui.control.Lamp
        Lamp_5                     matlab.ui.control.Lamp
        Lamp_4                     matlab.ui.control.Lamp
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        Slot24CheckBox             matlab.ui.control.CheckBox
        Slot23CheckBox             matlab.ui.control.CheckBox
        Slot22CheckBox             matlab.ui.control.CheckBox
        Slot21CheckBox             matlab.ui.control.CheckBox
        Slot20CheckBox             matlab.ui.control.CheckBox
        Slot19CheckBox             matlab.ui.control.CheckBox
        Slot18CheckBox             matlab.ui.control.CheckBox
        Slot17CheckBox             matlab.ui.control.CheckBox
        Slot16CheckBox             matlab.ui.control.CheckBox
        Slot15CheckBox             matlab.ui.control.CheckBox
        Slot14CheckBox             matlab.ui.control.CheckBox
        Slot13CheckBox             matlab.ui.control.CheckBox
        Slot12CheckBox             matlab.ui.control.CheckBox
        Slot11CheckBox             matlab.ui.control.CheckBox
        Slot10CheckBox             matlab.ui.control.CheckBox
        Slot9CheckBox              matlab.ui.control.CheckBox
        Slot8CheckBox              matlab.ui.control.CheckBox
        Slot7CheckBox              matlab.ui.control.CheckBox
        Slot6CheckBox              matlab.ui.control.CheckBox
        Slot5CheckBox              matlab.ui.control.CheckBox
        Slot4CheckBox              matlab.ui.control.CheckBox
        Slot3CheckBox              matlab.ui.control.CheckBox
        Slot2CheckBox              matlab.ui.control.CheckBox
        Slot1CheckBox              matlab.ui.control.CheckBox
        CARPARKINGINDICATORLabel   matlab.ui.control.Label
        AvailableTextArea          matlab.ui.control.TextArea
        AvailableTextAreaLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
    DriversView2Reference
    end
    
    methods (Access = public)
        
        function createAndSetupDriversView2(app)
            disp('Creating and setting up DriversView2');
            % Create an instance of DriversView
            driversView = DriversView2;
        
            % Set up the references
            app.DriversView2Reference = driversView;
            driversView.setOperatorsViewReference(app);
        
            % Initialize the parking layout in DriversView2
            driversView.initializeParking();
            driversView.initializeCarImages();
            
            % Update all cars immediately after creation
            app.updateAllCars();

            driversView.DriversViewApp.Visible = 'on';
        end

            function updateAllCars(app)
        disp('Updating all cars');
        for i = 1:24
            checkboxName = sprintf('Slot%dCheckBox', i);
            value = app.(checkboxName).Value;
            app.updateSlotStatus(i, value);
        end
        
        % Update DriversView2 if it exists
        if ~isempty(app.DriversView2Reference) && isvalid(app.DriversView2Reference)
            app.DriversView2Reference.updateAllCars();
            app.DriversView2Reference.highlightNearestAvailableSlot();
        else
            disp('DriversView2Reference is not valid or empty');
        end
    end


    end
    
    methods (Access = private)
        
     
        function updateSlotStatus(app, slotNumber, value)
            % Update lamp color and label text
            lampName = sprintf('Lamp_%d', slotNumber);
            labelName = sprintf('OpenLabel_%d', slotNumber);
            
            if slotNumber == 1
                lampName = 'Lamp';
                labelName = 'OpenLabel';
            end
            
            if value
                app.(lampName).Color = [1 0.6 0]; % Orange
                app.(labelName).Text = 'Closed';
                app.(labelName).FontColor = [1 0.6 0];
            else
                app.(lampName).Color = [0 1 0]; % Green
                app.(labelName).Text = 'Open';
                app.(labelName).FontColor = [0 1 0];
            end
            
            % Update the AvailableTextArea
            app.AvailableTextAreaValueChanging();
            
            % Update DriversView
            if ~isempty(app.DriversView2Reference) && isvalid(app.DriversView2Reference)
                disp(['Updating car image for slot ', num2str(slotNumber)]);
                app.DriversView2Reference.updateCarImage(slotNumber, value);
            else
                disp('DriversView2Reference is not valid or empty');
            end             
        end
        
            function SlotCheckBoxValueChanged(app, event)
                % Get the slot number from the checkbox name
                slotNumber = str2double(regexp(event.Source.Text, '\d+', 'match'));
                value = event.Value;
                disp(['Slot ', num2str(slotNumber), ' checkbox changed. New value: ', num2str(value)]);
                app.updateSlotStatus(slotNumber, value);
                if ~isempty(app.DriversView2Reference) && isvalid(app.DriversView2Reference)
                   app.DriversView2Reference.updateAllCars();
                    app.DriversView2Reference.highlightNearestAvailableSlot();
                end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: AvailableTextArea
        function AvailableTextAreaValueChanging(app, event)
            count = 24;
            for i = 1:24
                checkboxName = sprintf('Slot%dCheckBox', i);
                if app.(checkboxName).Value
                    count = count - 1;
                end
            end
            app.AvailableTextArea.Value = num2str(count);
            
            
        end

        % Button pushed function: SwitchtoDriversViewButton
        function SwitchtoDriversViewButtonPushed(app, event)
            disp('Switching to Drivers View');
            app.UIFigure.Visible = 'off';
            if isempty(app.DriversView2Reference) || ~isvalid(app.DriversView2Reference)
                disp('Creating new DriversView2');
                app.createAndSetupDriversView2();
            else
                disp('Using existing DriversView2');
                app.DriversView2Reference.DriversViewApp.Visible = 'on';
                app.updateAllCars();
            end
        end

        % Value changed function: Slot1CheckBox
        function Slot1CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot2CheckBox
        function Slot2CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot3CheckBox
        function Slot3CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot4CheckBox
        function Slot4CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot5CheckBox
        function Slot5CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);  
        end

        % Value changed function: Slot6CheckBox
        function Slot6CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot7CheckBox
        function Slot7CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot8CheckBox
        function Slot8CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot9CheckBox
        function Slot9CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot10CheckBox
        function Slot10CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot11CheckBox
        function Slot11CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot12CheckBox
        function Slot12CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot13CheckBox
        function Slot13CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot14CheckBox
        function Slot14CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot15CheckBox
        function Slot15CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot16CheckBox
        function Slot16CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);  
        end

        % Value changed function: Slot17CheckBox
        function Slot17CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot18CheckBox
        function Slot18CheckBoxValueChanged(app, event)
               app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot19CheckBox
        function Slot19CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);  
        end

        % Value changed function: Slot20CheckBox
        function Slot20CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot21CheckBox
        function Slot21CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot22CheckBox
        function Slot22CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot23CheckBox
        function Slot23CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);
        end

        % Value changed function: Slot24CheckBox
        function Slot24CheckBoxValueChanged(app, event)
            app.SlotCheckBoxValueChanged(event);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AvailableTextAreaLabel = uilabel(app.UIFigure);
            app.AvailableTextAreaLabel.Text = 'Available';
            app.AvailableTextArea = uitextarea(app.UIFigure);
            app.AvailableTextArea.ValueChangingFcn = createCallbackFcn(app, @AvailableTextAreaValueChanging, true);
            app.CARPARKINGINDICATORLabel = uilabel(app.UIFigure);
            app.CARPARKINGINDICATORLabel.Text = 'CAR PARKING INDICATOR';
            app.Slot1CheckBox = uicheckbox(app.UIFigure);
            app.Slot1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot1CheckBoxValueChanged, true);
            app.Slot1CheckBox.Text = 'Slot 1';
            app.Slot2CheckBox = uicheckbox(app.UIFigure);
            app.Slot2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot2CheckBoxValueChanged, true);
            app.Slot2CheckBox.Text = 'Slot 2';
            app.Slot3CheckBox = uicheckbox(app.UIFigure);
            app.Slot3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot3CheckBoxValueChanged, true);
            app.Slot3CheckBox.Text = 'Slot 3';
            app.Slot4CheckBox = uicheckbox(app.UIFigure);
            app.Slot4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot4CheckBoxValueChanged, true);
            app.Slot4CheckBox.Text = 'Slot 4';
            app.Slot5CheckBox = uicheckbox(app.UIFigure);
            app.Slot5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot5CheckBoxValueChanged, true);
            app.Slot5CheckBox.Text = 'Slot 5';
            app.Slot6CheckBox = uicheckbox(app.UIFigure);
            app.Slot6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot6CheckBoxValueChanged, true);
            app.Slot6CheckBox.Text = 'Slot 6';
            app.Slot7CheckBox = uicheckbox(app.UIFigure);
            app.Slot7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot7CheckBoxValueChanged, true);
            app.Slot7CheckBox.Text = 'Slot 7';
            app.Slot8CheckBox = uicheckbox(app.UIFigure);
            app.Slot8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot8CheckBoxValueChanged, true);
            app.Slot8CheckBox.Text = 'Slot 8';
            app.Slot9CheckBox = uicheckbox(app.UIFigure);
            app.Slot9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot9CheckBoxValueChanged, true);
            app.Slot9CheckBox.Text = 'Slot 9';
            app.Slot10CheckBox = uicheckbox(app.UIFigure);
            app.Slot10CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot10CheckBoxValueChanged, true);
            app.Slot10CheckBox.Text = 'Slot 10';
            app.Slot11CheckBox = uicheckbox(app.UIFigure);
            app.Slot11CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot11CheckBoxValueChanged, true);
            app.Slot11CheckBox.Text = 'Slot 11';
            app.Slot12CheckBox = uicheckbox(app.UIFigure);
            app.Slot12CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot12CheckBoxValueChanged, true);
            app.Slot12CheckBox.Text = 'Slot 12';
            app.Slot13CheckBox = uicheckbox(app.UIFigure);
            app.Slot13CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot13CheckBoxValueChanged, true);
            app.Slot13CheckBox.Text = 'Slot 13';
            app.Slot14CheckBox = uicheckbox(app.UIFigure);
            app.Slot14CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot14CheckBoxValueChanged, true);
            app.Slot14CheckBox.Text = 'Slot 14';
            app.Slot15CheckBox = uicheckbox(app.UIFigure);
            app.Slot15CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot15CheckBoxValueChanged, true);
            app.Slot15CheckBox.Text = 'Slot 15';
            app.Slot16CheckBox = uicheckbox(app.UIFigure);
            app.Slot16CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot16CheckBoxValueChanged, true);
            app.Slot16CheckBox.Text = 'Slot 16';
            app.Slot17CheckBox = uicheckbox(app.UIFigure);
            app.Slot17CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot17CheckBoxValueChanged, true);
            app.Slot17CheckBox.Text = 'Slot 17';
            app.Slot18CheckBox = uicheckbox(app.UIFigure);
            app.Slot18CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot18CheckBoxValueChanged, true);
            app.Slot18CheckBox.Text = 'Slot 18';
            app.Slot19CheckBox = uicheckbox(app.UIFigure);
            app.Slot19CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot19CheckBoxValueChanged, true);
            app.Slot19CheckBox.Text = 'Slot 19';
            app.Slot20CheckBox = uicheckbox(app.UIFigure);
            app.Slot20CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot20CheckBoxValueChanged, true);
            app.Slot20CheckBox.Text = 'Slot 20';
            app.Slot21CheckBox = uicheckbox(app.UIFigure);
            app.Slot21CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot21CheckBoxValueChanged, true);
            app.Slot21CheckBox.Text = 'Slot 21';
            app.Slot22CheckBox = uicheckbox(app.UIFigure);
            app.Slot22CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot22CheckBoxValueChanged, true);
            app.Slot22CheckBox.Text = 'Slot 22';
            app.Slot23CheckBox = uicheckbox(app.UIFigure);
            app.Slot23CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot23CheckBoxValueChanged, true);
            app.Slot23CheckBox.Text = 'Slot 23';
            app.Slot24CheckBox = uicheckbox(app.UIFigure);
            app.Slot24CheckBox.ValueChangedFcn = createCallbackFcn(app, @Slot24CheckBoxValueChanged, true);
            app.Slot24CheckBox.Text = 'Slot 24';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Lamp_9 = uilamp(app.UIFigure);
            app.Lamp_10 = uilamp(app.UIFigure);
            app.Lamp_11 = uilamp(app.UIFigure);
            app.Lamp_12 = uilamp(app.UIFigure);
            app.Lamp_13 = uilamp(app.UIFigure);
            app.Lamp_14 = uilamp(app.UIFigure);
            app.Lamp_15 = uilamp(app.UIFigure);
            app.Lamp_16 = uilamp(app.UIFigure);
            app.Lamp_17 = uilamp(app.UIFigure);
            app.Lamp_18 = uilamp(app.UIFigure);
            app.Lamp_19 = uilamp(app.UIFigure);
            app.Lamp_20 = uilamp(app.UIFigure);
            app.Lamp_21 = uilamp(app.UIFigure);
            app.Lamp_22 = uilamp(app.UIFigure);
            app.Lamp_23 = uilamp(app.UIFigure);
            app.Lamp_24 = uilamp(app.UIFigure);
            app.OpenLabel = uilabel(app.UIFigure);
            app.OpenLabel.Text = 'Open';
            app.OpenLabel_2 = uilabel(app.UIFigure);
            app.OpenLabel_2.Text = 'Open';
            app.OpenLabel_3 = uilabel(app.UIFigure);
            app.OpenLabel_3.Text = 'Open';
            app.OpenLabel_4 = uilabel(app.UIFigure);
            app.OpenLabel_4.Text = 'Open';
            app.OpenLabel_5 = uilabel(app.UIFigure);
            app.OpenLabel_5.Text = 'Open';
            app.OpenLabel_6 = uilabel(app.UIFigure);
            app.OpenLabel_6.Text = 'Open';
            app.OpenLabel_7 = uilabel(app.UIFigure);
            app.OpenLabel_7.Text = 'Open';
            app.OpenLabel_8 = uilabel(app.UIFigure);
            app.OpenLabel_8.Text = 'Open';
            app.OpenLabel_9 = uilabel(app.UIFigure);
            app.OpenLabel_9.Text = 'Open';
            app.OpenLabel_10 = uilabel(app.UIFigure);
            app.OpenLabel_10.Text = 'Open';
            app.OpenLabel_11 = uilabel(app.UIFigure);
            app.OpenLabel_11.Text = 'Open';
            app.OpenLabel_12 = uilabel(app.UIFigure);
            app.OpenLabel_12.Text = 'Open';
            app.OpenLabel_13 = uilabel(app.UIFigure);
            app.OpenLabel_13.Text = 'Open';
            app.OpenLabel_14 = uilabel(app.UIFigure);
            app.OpenLabel_14.Text = 'Open';
            app.OpenLabel_15 = uilabel(app.UIFigure);
            app.OpenLabel_15.Text = 'Open';
            app.OpenLabel_16 = uilabel(app.UIFigure);
            app.OpenLabel_16.Text = 'Open';
            app.OpenLabel_17 = uilabel(app.UIFigure);
            app.OpenLabel_17.Text = 'Open';
            app.OpenLabel_18 = uilabel(app.UIFigure);
            app.OpenLabel_18.Text = 'Open';
            app.OpenLabel_19 = uilabel(app.UIFigure);
            app.OpenLabel_19.Text = 'Open';
            app.OpenLabel_20 = uilabel(app.UIFigure);
            app.OpenLabel_20.Text = 'Open';
            app.OpenLabel_21 = uilabel(app.UIFigure);
            app.OpenLabel_21.Text = 'Open';
            app.OpenLabel_22 = uilabel(app.UIFigure);
            app.OpenLabel_22.Text = 'Open';
            app.OpenLabel_23 = uilabel(app.UIFigure);
            app.OpenLabel_23.Text = 'Open';
            app.OpenLabel_24 = uilabel(app.UIFigure);
            app.OpenLabel_24.Text = 'Open';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '/ 24';
            app.SwitchtoDriversViewButton = uibutton(app.UIFigure, 'push');
            app.SwitchtoDriversViewButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchtoDriversViewButtonPushed, true);
            app.SwitchtoDriversViewButton.Text = 'Switch to Driver''s View';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperatorsView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end